classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        manualinputEditField        matlab.ui.control.EditField
        manualinputEditFieldLabel   matlab.ui.control.Label
        manualinputSwitch           matlab.ui.control.Switch
        ChessBotLabel               matlab.ui.control.Label
        LogTextArea                 matlab.ui.control.TextArea
        LogTextAreaLabel            matlab.ui.control.Label
        showpicureaftermoveButton   matlab.ui.control.Button
        showpicurebeforemoveButton  matlab.ui.control.Button
        statusLamp                  matlab.ui.control.Lamp
        statusLampLabel             matlab.ui.control.Label
        startGameButton             matlab.ui.control.Button
        confirmMoveButton           matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % here all the properties form the cheesgame get declared

        status int8     % stores the status of the game ...
                        % 0:game has not started, 1:human has to move,
                        % 2:robot is moving, 3:game has ended, -1 : error

        robot       Robot
        chessbot    Chessbot
        camera      Camera

    end
    
    methods (Access = private)
        
        function [] = updateStatus(app, newStatus)
            % This function updates the status propertie and updates the new status on the app 
            %
            % === INPUT PARAMETERS ===
            % newStatus : id of the new status (0 ... 3)
            
            app.status = newStatus;

            switch app.status   %dependig on the status propertie the lamp and status text get changed
                case 0
                    
                    app.statusLampLabel.Text = 'setup';
                    app.statusLamp.Color = 'green';
                    %Open in.txt to write and close again (to delete any old data).
                    clearfile = fopen('C:\Users\lenna\OneDrive - TUM\Uni\Entickelung modularer Roboter\Schachroboter\IO\in.txt','w'); 
                    fclose(clearfile);
                    %Open in.txt again to read
                    Input = fopen('C:\Users\lenna\OneDrive - TUM\Uni\Entickelung modularer Roboter\Schachroboter\IO\in.txt','r'); 
                    
                    %Open out.txt to write (append)
                    Output = fopen('C:\Users\lenna\OneDrive - TUM\Uni\Entickelung modularer Roboter\Schachroboter\IO\out.txt','a');


                case 1
                    app.statusLampLabel.Text = 'human has to move';
                    app.statusLamp.Color = 'white';
                case 2
                    app.statusLampLabel.Text = 'robot has to move';
                    app.statusLamp.Color = 'black';
                case 3
                    app.statusLampLabel.Text = 'game has endded';
                    app.statusLamp.Color = 'red';
                otherwise
                    app.statusLampLabel.Text = 'error';
                    app.statusLamp.Color = 'red';
            end


        end

        function updateLog(app, newLog)
            % appends a new message string to the log

            currString = app.LogTextArea.Value;
            currString{end+1}=newLog;
            app.LogTextArea.Value = currString;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.robot = Robot();  % instatiate all the objects by calling their constructors 
            app.camera = Camera();
            app.chessbot = Chessbot();


            app.updateStatus(0);  % set status on setup mode
            app.updateLog('App has been startet, please start game');

        end

        % Button pushed function: confirmMoveButton
        function confirmMoveButtonPushed(app, event)
            if(app.status == 1)   %only execute when human is playing
                app.updateStatus(2);
                app.camera = app.camera.makePhoto(1);
                if(app.manualinputSwitch.Value == 'manual')
                    app.updateLog('');
                    app.updateLog('manual input:');
                    app.updateLog(app.manualinputEditField.Value);
                    move = app.manualinputEditField.Value;
                end
                if(app.manualinputSwitch.Value == 'camera')
                    app.updateLog('camera input:');
                    [move, beat, color, text] = app.camera.getMove()
                    app.updateLog(text);
                end

                robot_move = app.chessbot.calculateMove(move);
                app.updateLog(robot_move);

                %app.robot.makeMove(robot_move);

                app.camera = app.camera.makePhoto(0);
                app.updateStatus(1);
            end
        end

        % Button pushed function: startGameButton
        function startGameButtonPushed(app, event)
            if(app.status == 0)
                app.camera = app.camera.makePhoto(0);
                app.updateStatus(1);
                app.updateLog('game started');
                app.updateLog('please make a move and confirm');
            end
        end

        % Button pushed function: showpicurebeforemoveButton
        function showpicurebeforemoveButtonPushed(app, event)
            app.camera.showPicture(0);
        end

        % Button pushed function: showpicureaftermoveButton
        function showpicureaftermoveButtonPushed(app, event)
            app.camera.showPicture(1);
        end

        % Callback function
        function showbothpicturesButtonPushed(app, event)
            app.camera.showPicture(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.confirmMoveButton = uibutton(app.UIFigure, 'push');
            app.confirmMoveButton.ButtonPushedFcn = createCallbackFcn(app, @confirmMoveButtonPushed, true);
            app.confirmMoveButton.Text = 'confirm Move';
            app.startGameButton = uibutton(app.UIFigure, 'push');
            app.startGameButton.ButtonPushedFcn = createCallbackFcn(app, @startGameButtonPushed, true);
            app.startGameButton.Text = 'start Game';
            app.statusLampLabel = uilabel(app.UIFigure);
            app.statusLampLabel.Text = 'status';
            app.statusLamp = uilamp(app.UIFigure);
            app.showpicurebeforemoveButton = uibutton(app.UIFigure, 'push');
            app.showpicurebeforemoveButton.ButtonPushedFcn = createCallbackFcn(app, @showpicurebeforemoveButtonPushed, true);
            app.showpicurebeforemoveButton.Text = 'show picure before move';
            app.showpicureaftermoveButton = uibutton(app.UIFigure, 'push');
            app.showpicureaftermoveButton.ButtonPushedFcn = createCallbackFcn(app, @showpicureaftermoveButtonPushed, true);
            app.showpicureaftermoveButton.Text = 'show picure after move';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.ChessBotLabel = uilabel(app.UIFigure);
            app.ChessBotLabel.Text = 'Chess Bot';
            app.manualinputSwitch = uiswitch(app.UIFigure, 'slider');
            app.manualinputSwitch.Items = {'camera', 'manual'};
            app.manualinputSwitch.Value = 'camera';
            app.manualinputEditFieldLabel = uilabel(app.UIFigure);
            app.manualinputEditFieldLabel.Text = 'manual input';
            app.manualinputEditField = uieditfield(app.UIFigure, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end